classdef tag_KS_clusters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        load_button           matlab.ui.control.Button
        axes                  matlab.ui.control.UIAxes
        clust_text            matlab.ui.control.Label
        assign_single_button  matlab.ui.control.Button
        assign_mua_button     matlab.ui.control.Button
        assign_noise_button   matlab.ui.control.Button
        back_button           matlab.ui.control.Button
        open_dir_button       matlab.ui.control.Button
        exp_text              matlab.ui.control.Label
        ch_text               matlab.ui.control.Label
        elec_text             matlab.ui.control.Label
        next_button           matlab.ui.control.Button
        current_tag_text      matlab.ui.control.Label
        lamp                  matlab.ui.control.Lamp
        save_button           matlab.ui.control.Button
        debug_button          matlab.ui.control.Button
    end


    properties (Access = public)
        data % Description
    end
    
    methods (Access = public)
        
        function [image_file_names, elec_list, ch_list] = parse_images_list(app, ks_exp_dir)
            
            % Inputs:
            %     kd_exp_dir - (str) - Full path to the experiment directory
            % 
            % Outpus:
            %     image_file_names - (Nx1 cell array of str) - List of full paths to images of cluster stats
            %     elec_ch_list - (Nx2 matrix) - First column is electrode (the dominant contact of the template), 
            %                                   Second is the cluster number
            
            image_file_names = dir(fullfile(ks_exp_dir, app.data.stats_folder));
            image_file_names = {image_file_names.name};
            image_file_names = image_file_names(~startsWith(image_file_names, '.'));  % remove the '.' and '..'
            out = regexp(image_file_names', 'stats_e(\d+)_ch_(\d+).png', 'tokens', 'once');
            f_inds = str2double(string([out{:}]));
            elec_ch_list = reshape(f_inds, 2, []);
            elec_list = elec_ch_list(1, :);
            ch_list = elec_ch_list(2, :);
            app.data.n_clusts = size(elec_ch_list, 2);
        end
        
        function present_next_cluster(app)
            curr_ind = app.data.curr_clust_ind;
            
            im_path = fullfile(app.data.ks_path, app.data.stats_folder, app.data.image_list{curr_ind});
            im = imread(im_path);
            im = imresize(im, 0.3);
            if ~isfield(app.data, 'h_im')
                app.data.h_im = imshow(im, 'parent', app.axes, 'InitialMagnification', 'fit');
            else
                app.data.h_im.CData = im;
            end
            
            app.clust_text.Text = ['Clust. No.: ' num2str(curr_ind) '/' num2str(length(app.data.image_list))];
            app.ch_text.Text = ['Ch. ' num2str(app.data.ch_list(curr_ind))];
            app.elec_text.Text = ['elec. ' num2str(app.data.elec_list(curr_ind))];
            
            % === Show the current tag ====
            curr_tag = app.data.tags(curr_ind);
            app.exp_text.Text = ['File: ' im_path];
            app.set_tag_name_and_color(curr_tag);

        end
        
        function set_tag_name_and_color(app, curr_tag)
            app.current_tag_text.Text = ['Current tag: ' curr_tag];
            if strcmp(curr_tag, 'Single')
                app.lamp.Color = [0.39,0.83,0.07];
            elseif strcmp(curr_tag, 'MUA')
                app.lamp.Color = [0.93,0.69,0.13];
            elseif strcmp(curr_tag, 'Noise')
                app.lamp.Color = [1.00,0.41,0.16];
            elseif strcmp(curr_tag, 'Unset')
                app.lamp.Color = [0.90,0.90,0.90];
            else
                1;
            end
            
            if sum(strcmp(app.data.tags, 'Unset')) == 0
                app.clust_text.BackgroundColor = [0.47,0.67,0.19];
                app.save_button.Enable = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: load_button
        function load_buttonButtonPushed(app, event)
            BASE_DIR = 'F:\';
            
            app.data.NA_db_name = 'final_NA_results.mat';
            app.data.stats_folder = 'single_unit_stats';
            
            [pathname] = uigetdir(BASE_DIR, 'Select ks_output folder');
            h_wb = waitbar(0.3, 'Loading data...');
            if isnumeric(pathname)
                 disp('User pressed cancel')
                 return;
            end
            app.save_button.Enable = false; % AP when reloading a new folder, disable save button again
            app.save_button.BackgroundColor = [0.90, 0.90, 0.90];
            
            app.data.ks_path = fullfile(pathname);
            app.exp_text.Text = ['Experiment folder: ' pathname];
            
            [image_list, elec_list, ch_list] = app.parse_images_list(pathname);
            [~, order_by_ch] = sort(ch_list, 'ascend');
            app.data.image_list = image_list(order_by_ch);
            app.data.elec_list = elec_list(order_by_ch);
            app.data.ch_list = ch_list(order_by_ch);
            app.data.tags = repmat("Unset" ,1, length(image_list));
            disp('=== Data loaded succesfully! ===');
            
            app.data.curr_clust_ind = 1;
            app.present_next_cluster();
            delete(h_wb);
        end

        % Button pushed function: assign_mua_button, 
        % assign_noise_button, assign_single_button
        function assign_new_tag(app, event)
            event.Source.BackgroundColor = [0.65,0.65,0.65];
            drawnow();
            curr_tag = event.Source.Text;
            app.data.tags(app.data.curr_clust_ind) = curr_tag;
            app.set_tag_name_and_color(curr_tag);
            app.next_buttonButtonPushed(777);
            event.Source.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: save_button
        function save_buttonButtonPushed(app, event)
            tags = app.data.tags;
            
            target_full_path = fullfile(app.data.ks_path, app.data.NA_db_name);
            NA_db = load(target_full_path);
            
            % Reorder the NA by the cluster index, unique ID
            ch_nums = [NA_db.NA_db.ch_num];
            [~, new_order] = sort(ch_nums);
            metadata = NA_db.metadata;
            NA_db = NA_db.NA_db(new_order);
            [NA_db.manual_tag] = tags{:};
            save(target_full_path, 'NA_db', 'metadata');
            app.save_button.BackgroundColor = [0.91, 1.00, 0.84];
            disp(['=== Cluster tags were saved as `manual_tag` to: ' target_full_path ' ===']);
        end

        % Button pushed function: back_button
        function back_buttonButtonPushed(app, event)
            if app.data.curr_clust_ind == 1
                return;
            end
            app.data.curr_clust_ind = app.data.curr_clust_ind - 1;
            app.present_next_cluster();
        end

        % Button pushed function: next_button
        function next_buttonButtonPushed(app, event)
            if app.data.curr_clust_ind == app.data.n_clusts
                return;
            end
            app.data.curr_clust_ind = app.data.curr_clust_ind + 1;
            app.present_next_cluster();
        end

        % Button pushed function: debug_button
        function debug_buttonButtonPushed(app, event)
            1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.load_button = uibutton(app.UIFigure, 'push');
            app.load_button.ButtonPushedFcn = createCallbackFcn(app, @load_buttonButtonPushed, true);
            app.load_button.Text = {'Load KiloSort'; 'result folder'};
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, '')
            xlabel(app.axes, '')
            ylabel(app.axes, '')
            app.clust_text = uilabel(app.UIFigure);
            app.clust_text.Text = 'Cluster No: -- / --';
            app.assign_single_button = uibutton(app.UIFigure, 'push');
            app.assign_single_button.ButtonPushedFcn = createCallbackFcn(app, @assign_new_tag, true);
            app.assign_single_button.Text = 'Single';
            app.assign_mua_button = uibutton(app.UIFigure, 'push');
            app.assign_mua_button.ButtonPushedFcn = createCallbackFcn(app, @assign_new_tag, true);
            app.assign_mua_button.Text = 'MUA';
            app.assign_noise_button = uibutton(app.UIFigure, 'push');
            app.assign_noise_button.ButtonPushedFcn = createCallbackFcn(app, @assign_new_tag, true);
            app.assign_noise_button.Text = 'Noise';
            app.back_button = uibutton(app.UIFigure, 'push');
            app.back_button.ButtonPushedFcn = createCallbackFcn(app, @back_buttonButtonPushed, true);
            app.back_button.Text = {'Back'; '<<'};
            app.open_dir_button = uibutton(app.UIFigure, 'push');
            app.open_dir_button.Text = 'Open dir';
            app.exp_text = uilabel(app.UIFigure);
            app.exp_text.Text = 'Experiment: -----';
            app.ch_text = uilabel(app.UIFigure);
            app.ch_text.Text = 'Ch.: ---';
            app.elec_text = uilabel(app.UIFigure);
            app.elec_text.Text = 'Elec.: ---';
            app.next_button = uibutton(app.UIFigure, 'push');
            app.next_button.ButtonPushedFcn = createCallbackFcn(app, @next_buttonButtonPushed, true);
            app.next_button.Text = {'Next'; '>>'};
            app.current_tag_text = uilabel(app.UIFigure);
            app.current_tag_text.Text = 'Current tag: ';
            app.lamp = uilamp(app.UIFigure);
            app.save_button = uibutton(app.UIFigure, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_buttonButtonPushed, true);
            app.save_button.Enable = 'off';
            app.save_button.Text = 'Save!';
            app.debug_button = uibutton(app.UIFigure, 'push');
            app.debug_button.ButtonPushedFcn = createCallbackFcn(app, @debug_buttonButtonPushed, true);
            app.debug_button.Text = 'Dbg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tag_KS_clusters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end